classdef BarScanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BrowseBarcodeImageButton  matlab.ui.control.Button
        ShowResultButton          matlab.ui.control.Button
        BarcodeNoLabel            matlab.ui.control.Label
        BarcodeScannerLabel       matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseBarcodeImageButton
        function BrowseBarcodeImageButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif;*.tiff'
                'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif, *.tiff)'}, 'Select an Image');
            if isequal(fileName, 0)
                disp('No image selected. Exiting.');
                return;  % User canceled the file selection
            end

            % Construct the full path to the selected image
            imageFilePath = fullfile(filePath, fileName);

            % Load the selected image
            app.x = imread(imageFilePath);
            % Display the image in the ImageAxes component
            imshow(app.x, 'Parent', app.UIAxes);            
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            msg = readBarcode(app.x);

            if ~isempty(msg)
                app.BarcodeNoLabel.Text = msg;
            else
                app.BarcodeNoLabel.Text = 'Barcode not detected';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseBarcodeImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseBarcodeImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBarcodeImageButtonPushed, true);
            app.BrowseBarcodeImageButton.Text = 'Browse Barcode Image';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.BarcodeNoLabel = uilabel(app.UIFigure);
            app.BarcodeNoLabel.Text = 'Barcode No';
            app.BarcodeScannerLabel = uilabel(app.UIFigure);
            app.BarcodeScannerLabel.Text = 'Barcode Scanner';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BarScanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end